classdef RoboticsPackage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        WelcomeTab                     matlab.ui.container.Tab
        STARTButton                    matlab.ui.control.Button
        ArmDefinitionTab               matlab.ui.container.Tab
        AddJointstoArmLabel            matlab.ui.control.Label
        JointTypeButtonGroup           matlab.ui.container.ButtonGroup
        RevoluteButton                 matlab.ui.control.RadioButton
        PrismaticButton                matlab.ui.control.RadioButton
        EnterDHParametersPanel         matlab.ui.container.Panel
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        thetaEditFieldLabel            matlab.ui.control.Label
        thetaEditField                 matlab.ui.control.NumericEditField
        CurrentArmLabel                matlab.ui.control.Label
        FinishedButton                 matlab.ui.control.Button
        ClearArmandStartOverButton     matlab.ui.control.Button
        LinkLengthmEditFieldLabel      matlab.ui.control.Label
        LinkLengthmEditField           matlab.ui.control.NumericEditField
        CurrentArmTable                matlab.ui.control.Table
        AddAboveJointtoArmButton       matlab.ui.control.Button
        ORLabel                        matlab.ui.control.Label
        FindDHParametersAutomaticallyPanel  matlab.ui.container.Panel
        ZAxisofJointLabel              matlab.ui.control.Label
        DirectionofLinki1WRTBaseFrameLabel  matlab.ui.control.Label
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        XEditField_4Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        AddAboveJointtoArmButton_2     matlab.ui.control.Button
        LinkPlanarButton               matlab.ui.control.Button
        DefaultArmConfigurationsLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        LinearGrasperButton            matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        SimulationTab                  matlab.ui.container.Tab
        SimulationLabel                matlab.ui.control.Label
        PlotRobotArmButton             matlab.ui.control.Button
        DriveRobotArmButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Comments show defintions of variables used throughout the code.
        % These are accessed with "app.VARIABLENAME". 
        % N is the number of links. 
        % Variables representing physical constants are in SI base
        % units.
        
        % Variables used throughout the code:
        arm_dh = [];    % 4 x N matrix of DH parameters in the order [theta,d,a,alpha]
        linkLengths = []; % N x 1 vector of link lengths, in meters
        jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
        armLinks = {};  % 1 x N array of Link objects from robotics toolbox
        ARM; % Robot arm, SerialLink object
        qlim = [];  % N x 2 array of joint limits (min, max)
        
        % Variables used in the DH parameters section only
        jointi = 1; % Counter used to keep track of which joint is being edited in arm creation
        armTable;   % A table object used to display DH parameters
        jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
        jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
        offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
    end
    
    methods (Access = private)
        % Functions used throughout the app.   
        
        % Used for finding DH parameters automatically
        function [dhi] = DHFinder(app,Z,U,LinkLength)
            %{
            Inputs:
            Z: Z axis of joint i, 1 x 3
            U: direction of link i-1 w.r.t base frame 1 x 3
            LinkLength: Length of the link
            
            Outputs:
            dhi: a collection of dh parameters for this joint i [theta d a
            alpha]
            %}
            
            %Normalize Z and U
            for i=1:size(Z,1)
                Z(i,:)=Z(i,:)/norm(Z(i,:));
                U(i,:)=U(i,:)/norm(U(i,:));
            end
            
            %Find X
            X=zeros(size(Z,1),3);
            for i=1:size(Z,1)
                if i==1
                    X(i,:)=cross([0,0,1],Z(i,:));
                else
                    X(i,:)=cross(Z(i-1,:),Z(i,:));
                end
                if X(i,:)==[0,0,0]
                    if i==1
                        X(i,:)=[0,0,1];
                    else
                        X(i,:)=X(i-1,:);
                    end
                end
            end
            
            %Find O for q=[0]
            O=zeros(size(Z,1),3);
            for i=1:size(Z,1)
                if i==1
                    O(i,:)=U(i,:)*LinkLength(i);
                else
                    O(i,:)=O(i-1,:)+U(i,:)*LinkLength(i);
                end
            end
            
            %Find a
            a=zeros(size(Z,1),1);
            for i=1:size(Z,1)
                P=(X(i,:)'*X(i,:))/(X(i,:)*X(i,:)');
                if i==1
                    Pb=P*O(i,:)';
                else
                    Pb=P*(O(i,:)-O(i-1,:))';
                end
                a(i)=norm(Pb);
            end
            
            %Find d
            d=zeros(size(Z,1),1);
            for i=1:size(Z,1)
                if i==1
                    Zi1=[0 0 1];
                    P=(Zi1'*Zi1)/(Zi1*Zi1');
                    Pb=P*O(i,:)';
                else
                    P=(Z(i-1,:)'*Z(i-1,:))/(Z(i-1,:)*Z(i-1,:)');
                    Pb=P*(O(i,:)-O(i-1,:))';
                end
                d(i)=norm(Pb);
            end
            
            %Find alpha and theta
            alpha=zeros(size(Z,1),1);
            theta=zeros(size(Z,1),1);
            for i=1:size(Z,1)
                if i==1
                    alpha(i)=acos(dot([0 0 1],Z(i,:)));
                    theta(i)=acos(dot([1 0 0],X(i,:)));
                else
                    alpha(i)=acos(dot(Z(i-1,:),Z(i,:)));
                    theta(i)=acos(dot(X(i-1,:),X(i,:)));
                end
            end
            dhi=[theta d a alpha];
            for i=1:size(dhi,1)
                for j=1:size(dhi,2)
                    if isnan(dhi(i,j))
                      dhi(i,j)=0;  
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clear all; close all
            % Run robotics toolbox startup file
            startup_rvc
            
        end

        % Button pushed function: AddAboveJointtoArmButton
        function AddAboveJointtoArmButtonPushed(app, event)
            % Get values
            LinkLength = app.LinkLengthmEditField.Value; % Get link length
            dhi = [app.thetaEditField.Value,app.dEditField.Value,app.aEditField.Value,app.alphaEditField.Value]; % Get DH parameters
            
            % Update app values
            app.linkLengths = [app.linkLengths;LinkLength]; % Update link lengths
            app.jointsTable(app.jointi) = {num2str(app.jointi)};  % Update row labels (joints) in table
            app.arm_dh = [app.arm_dh;dhi];  % Update arm dh parameters
            
            % Read radio button joint type value, convert to a string,
            % append onto list of joint types
            app.jointTypes = [app.jointTypes;convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text)];
            
            % Update boolean vector that contains joint types as 0 or 1
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.jointTypesBool(app.jointi) = 0;
            else
                   app.jointTypesBool(app.jointi) = 1;
            end
            
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Update the offset (dh parameter value for the joint variable)
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.offset(app.jointi) = theta(app.jointi);
            else
                   app.offset(app.jointi) = d(app.jointi);
            end
            
            % Combine all the current links into an arm:
            app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset')
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = app.jointi + 1;    % Increment joint
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SimulationTab;   % Change to "Simulation" when you press finished on arm creation
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ArmDefinitionTab;    % Change to "Arm Definition" when you first start
        end

        % Button pushed function: AddAboveJointtoArmButton_2
        function AddAboveJointtoArmButton_2Pushed(app, event)
            % Find DH Parameters for user
            
            % Get values
            LinkLength = app.LinkLengthmEditField.Value; % Get link length
            Z = [app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value];
            U = [app.XEditField_2.Value,app.YEditField_2.Value,app.ZEditField_2.Value];
            
            
            % Use "DHFinder" function to find the DH Parameters:
            dhi = app.DHFinder(Z,U,LinkLength);
            
            % Update app values
            app.linkLengths = [app.linkLengths;LinkLength]; % Update link lengths
            app.jointsTable(app.jointi) = {num2str(app.jointi)};  % Update row labels (joints) in table
            app.arm_dh = [app.arm_dh;dhi];  % Update arm dh parameters
            
            % Read radio button joint type value, convert to a string,
            % append onto list of joint types
            app.jointTypes = [app.jointTypes;convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text)];
            
            % Update boolean vector that contains joint types as 0 or 1
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.jointTypesBool(app.jointi) = 0;
            else
                   app.jointTypesBool(app.jointi) = 1;
            end
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Update the offset (dh parameter value for the joint variable)
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.offset(app.jointi) = theta(app.jointi);
            else
                   app.offset(app.jointi) = d(app.jointi);
            end
            
            % Combine all the current links into an arm:
            app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset');
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = app.jointi + 1;    % Increment joint
        end

        % Button pushed function: ClearArmandStartOverButton
        function ClearArmandStartOverButtonPushed(app, event)
            % Clears all the variables defined during arm creation. Also
            % resets the table
                       
            app.arm_dh = [];    % 4 x N matrix of DH parameters in the order [a,alpha,d,theta]
            app.linkLengths = []; % N x 1 vector of link lengths, in meters
            app.jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
            app.armLinks = {};  % 1 x N array of Link objects from robotics toolbox
            app.jointi = 1; % Counter used to keep track of which joint is being edited in arm creation
            app.jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
            app.jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
            app.offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
            
            % Empty the table
            JointType = [];
            Length = [];
            a = [];
            alpha = [];
            d = [];
            theta = [];
            app.armTable = table(JointType,Length,a,alpha,d,theta,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
        end

        % Button pushed function: PlotRobotArmButton
        function PlotRobotArmButtonPushed(app, event)
            % Plot robot arm at initial state (all joint variables = 0)
            q = zeros(1,size(app.arm_dh,1));
            app.ARM.plot(q)
        end

        % Button pushed function: DriveRobotArmButton
        function DriveRobotArmButtonPushed(app, event)
            teach(app.ARM)
        end

        % Button pushed function: LinkPlanarButton
        function LinkPlanarButtonPushed(app, event)
            % Default Arm Configuration: 3 Link Planar Arm
            % Defines a 3 link planar arm with arm length 1
            
            % Clears all the variables defined during arm creation. Also
            % resets the table
                       
            app.arm_dh = [];    % 4 x N matrix of DH parameters in the order [a,alpha,d,theta]
            app.linkLengths = []; % N x 1 vector of link lengths, in meters
            app.jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
            app.armLinks = {};  % 1 x N array of Link objects from robotics toolbox
            app.jointi = 1; % Counter used to keep track of which joint is being edited in arm creation
            app.jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
            app.jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
            app.offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
            
            % Empty the table
            JointType = [];
            Length = [];
            a = [];
            alpha = [];
            d = [];
            theta = [];
            app.armTable = table(JointType,Length,a,alpha,d,theta,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            
            % Now, define the 3 link planar arm
            % Update app values
            app.linkLengths = [1;1;1]; % Link Lengths are all 1
            app.jointsTable = {'1','2','3'};  % Update row labels (joints) in table
            app.arm_dh = [0,0,1,0;0,0,1,0;0,0,1,0];  % Update arm dh parameters
            
            % Update joint types
            app.jointTypes = ["Revolute";"Revolute";"Revolute"];
            
            % Update boolean vector that contains joint types as 0 or 1
            app.jointTypesBool = [0,0,0];
            
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Offset is 0 for the 3 link planar arm
            app.offset = [0,0,0];
            
            % Define joint limits:
            app.qlim = [-pi/2,pi/2;-pi/2,pi/2;-pi/2,pi/2];
          
            
            % Combine all the current links into an arm:
            app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset','qlim',app.qlim);
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = 3;    % Increment joint
            
        end

        % Button pushed function: LinearGrasperButton
        function LinearGrasperButtonPushed(app, event)
            % Default Arm Configuration: Linear Grasper
            % Defines a "linear grasper" (2 revolute joints and 1
            % prismatic)
            
            % Clears all the variables defined during arm creation. Also
            % resets the table
                       
            app.arm_dh = [];    % 4 x N matrix of DH parameters in the order [a,alpha,d,theta]
            app.linkLengths = []; % N x 1 vector of link lengths, in meters
            app.jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
            app.armLinks = {};  % 1 x N array of Link objects from robotics toolbox
            app.jointi = 1; % Counter used to keep track of which joint is being edited in arm creation
            app.jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
            app.jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
            app.offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
            
            % Empty the table
            JointType = [];
            Length = [];
            a = [];
            alpha = [];
            d = [];
            theta = [];
            app.armTable = table(JointType,Length,a,alpha,d,theta,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            
            % Now, define the 3 link planar arm
            % Update app values
            app.linkLengths = [1;1;1]; % Link Lengths are all 1
            app.jointsTable = {'1','2','3'};  % Update row labels (joints) in table
            app.arm_dh = [0,0,1,pi/2;0,0,1,pi/2;0,0,1,0];  % Update arm dh parameters
            
            % Update joint types
            app.jointTypes = ["Revolute";"Revolute";"Prismatic"];
            
            % Update boolean vector that contains joint types as 0 or 1
            app.jointTypesBool = [0,0,1];
            
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Offset is pi/2 for second revolute joint
            app.offset = [0,pi/2,0];
            
            % Define joint limits:
            app.qlim = [-pi/2,pi/2;-pi/2,pi/2;-.25,1.5];
            
            % Combine all the current links into an arm:
            app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset','qlim',app.qlim);
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = 3;    % Increment joint
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome!';
            app.STARTButton = uibutton(app.WelcomeTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ArmDefinitionTab = uitab(app.TabGroup);
            app.ArmDefinitionTab.Title = 'Arm Definition';
            app.AddJointstoArmLabel = uilabel(app.ArmDefinitionTab);
            app.AddJointstoArmLabel.Text = 'Add Joints to Arm';
            app.JointTypeButtonGroup = uibuttongroup(app.ArmDefinitionTab);
            app.JointTypeButtonGroup.Title = 'Joint Type';
            app.RevoluteButton = uiradiobutton(app.JointTypeButtonGroup);
            app.RevoluteButton.Text = 'Revolute';
            app.RevoluteButton.Value = true;
            app.PrismaticButton = uiradiobutton(app.JointTypeButtonGroup);
            app.PrismaticButton.Text = 'Prismatic';
            app.EnterDHParametersPanel = uipanel(app.ArmDefinitionTab);
            app.EnterDHParametersPanel.Title = 'Enter DH Parameters:';
            app.aEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.thetaEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.CurrentArmLabel = uilabel(app.ArmDefinitionTab);
            app.CurrentArmLabel.Text = 'Current Arm:';
            app.FinishedButton = uibutton(app.ArmDefinitionTab, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished!';
            app.ClearArmandStartOverButton = uibutton(app.ArmDefinitionTab, 'push');
            app.ClearArmandStartOverButton.ButtonPushedFcn = createCallbackFcn(app, @ClearArmandStartOverButtonPushed, true);
            app.ClearArmandStartOverButton.Text = 'Clear Arm and Start Over';
            app.LinkLengthmEditFieldLabel = uilabel(app.ArmDefinitionTab);
            app.LinkLengthmEditFieldLabel.Text = 'Link Length (m)';
            app.LinkLengthmEditField = uieditfield(app.ArmDefinitionTab, 'numeric');
            app.CurrentArmTable = uitable(app.ArmDefinitionTab);
            app.CurrentArmTable.ColumnName = {'JointType'; 'Length'; 'a'; 'alpha'; 'd'; 'theta'};
            app.CurrentArmTable.RowName = {};
            app.AddAboveJointtoArmButton = uibutton(app.ArmDefinitionTab, 'push');
            app.AddAboveJointtoArmButton.ButtonPushedFcn = createCallbackFcn(app, @AddAboveJointtoArmButtonPushed, true);
            app.AddAboveJointtoArmButton.Text = 'Add Above Joint to Arm';
            app.ORLabel = uilabel(app.ArmDefinitionTab);
            app.ORLabel.Text = '----------- OR -----------';
            app.FindDHParametersAutomaticallyPanel = uipanel(app.ArmDefinitionTab);
            app.FindDHParametersAutomaticallyPanel.Title = 'Find DH Parameters Automatically:';
            app.ZAxisofJointLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.ZAxisofJointLabel.Text = 'Z Axis of Joint';
            app.DirectionofLinki1WRTBaseFrameLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.DirectionofLinki1WRTBaseFrameLabel.Text = {'Direction of Link i-1 '; 'WRT Base Frame'};
            app.XEditFieldLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.XEditField_4Label = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.AddAboveJointtoArmButton_2 = uibutton(app.ArmDefinitionTab, 'push');
            app.AddAboveJointtoArmButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddAboveJointtoArmButton_2Pushed, true);
            app.AddAboveJointtoArmButton_2.Text = 'Add Above Joint to Arm';
            app.LinkPlanarButton = uibutton(app.ArmDefinitionTab, 'push');
            app.LinkPlanarButton.ButtonPushedFcn = createCallbackFcn(app, @LinkPlanarButtonPushed, true);
            app.LinkPlanarButton.Text = '3 Link Planar';
            app.DefaultArmConfigurationsLabel = uilabel(app.ArmDefinitionTab);
            app.DefaultArmConfigurationsLabel.Text = 'Default Arm Configurations';
            app.Image = uiimage(app.ArmDefinitionTab);
            app.Image.ImageSource = '3LinkArm.png';
            app.LinearGrasperButton = uibutton(app.ArmDefinitionTab, 'push');
            app.LinearGrasperButton.ButtonPushedFcn = createCallbackFcn(app, @LinearGrasperButtonPushed, true);
            app.LinearGrasperButton.Text = 'Linear Grasper';
            app.Image2 = uiimage(app.ArmDefinitionTab);
            app.Image2.ImageSource = 'Manipulator.png';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationLabel = uilabel(app.SimulationTab);
            app.SimulationLabel.Text = 'Simulation';
            app.PlotRobotArmButton = uibutton(app.SimulationTab, 'push');
            app.PlotRobotArmButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRobotArmButtonPushed, true);
            app.PlotRobotArmButton.Text = 'Plot Robot Arm';
            app.DriveRobotArmButton = uibutton(app.SimulationTab, 'push');
            app.DriveRobotArmButton.ButtonPushedFcn = createCallbackFcn(app, @DriveRobotArmButtonPushed, true);
            app.DriveRobotArmButton.Text = 'Drive Robot Arm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboticsPackage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end