classdef RoboticsPackage_part4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WelcomeTab                      matlab.ui.container.Tab
        STARTButton                     matlab.ui.control.Button
        ArmDefinitionTab                matlab.ui.container.Tab
        EnterLinkandJointPropertiesPanel  matlab.ui.container.Panel
        JointTypeButtonGroup            matlab.ui.container.ButtonGroup
        RevoluteButton                  matlab.ui.control.RadioButton
        PrismaticButton                 matlab.ui.control.RadioButton
        LinkLengthmEditFieldLabel       matlab.ui.control.Label
        LinkLengthmEditField            matlab.ui.control.NumericEditField
        LinkMasskgEditFieldLabel        matlab.ui.control.Label
        LinkMasskgEditField             matlab.ui.control.NumericEditField
        MotorMasskgEditFieldLabel       matlab.ui.control.Label
        MotorMasskgEditField            matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel         matlab.ui.control.Label
        GearRatioEditField              matlab.ui.control.NumericEditField
        LinkInertiaMatrixPanel          matlab.ui.container.Panel
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        MotorInertiakgm2Label           matlab.ui.control.Label
        MotorInertiakgm2EditField       matlab.ui.control.NumericEditField
        JointLimitminEditFieldLabel     matlab.ui.control.Label
        JointLimitminEditField          matlab.ui.control.NumericEditField
        JointLimitmaxEditFieldLabel     matlab.ui.control.Label
        JointLimitmaxEditField          matlab.ui.control.NumericEditField
        enteranglesindegreesLabel       matlab.ui.control.Label
        FrictionCoefficientBEditFieldLabel  matlab.ui.control.Label
        FrictionCoefficientBEditField   matlab.ui.control.NumericEditField
        FrictionCoefficientfcEditFieldLabel  matlab.ui.control.Label
        FrictionCoefficientfcEditField  matlab.ui.control.NumericEditField
        FrictionCoefficientfcEditFieldLabel2  matlab.ui.control.Label
        FrictionCoefficientfcEditField2  matlab.ui.control.NumericEditField
        AddJointstoArmLabel             matlab.ui.control.Label
        CurrentArmLabel                 matlab.ui.control.Label
        FinishedButton                  matlab.ui.control.Button
        ClearArmandStartOverButton      matlab.ui.control.Button
        CurrentArmTable                 matlab.ui.control.Table
        LinkPlanarButton                matlab.ui.control.Button
        DefaultArmConfigurationsLabel   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        LinearGrasperButton             matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        EnterorFindDHParametersPanel    matlab.ui.container.Panel
        EnterDHParametersPanel          matlab.ui.container.Panel
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        alphadegreesEditFieldLabel      matlab.ui.control.Label
        alphadegreesEditField           matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        thetaEditFieldLabel             matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.NumericEditField
        ORLabel                         matlab.ui.control.Label
        FindDHParametersAutomaticallyPanel  matlab.ui.container.Panel
        ZAxisofJointLabel               matlab.ui.control.Label
        DirectionofLinki1WRTBaseFrameLabel  matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        XEditField_4Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        AddAboveJointtoArmButton_2      matlab.ui.control.Button
        AddAboveJointtoArmButton        matlab.ui.control.Button
        SimulationTab                   matlab.ui.container.Tab
        SimulationHomePageLabel         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        PlottingPanel                   matlab.ui.container.Panel
        PlotRobotArmButton              matlab.ui.control.Button
        DriveRobotArmButton             matlab.ui.control.Button
        DynamicsPanel                   matlab.ui.container.Panel
        PrintEquationsofMotionButton    matlab.ui.control.Button
        SimulateSystemDynamicsButton    matlab.ui.control.Button
        IndirectForceControlPanel       matlab.ui.container.Panel
        ComplianceControlButton         matlab.ui.control.Button
        ImpedanceControlButton          matlab.ui.control.Button
        BacktoArmDefinitionButton       matlab.ui.control.Button
        SystemDynamicsTab               matlab.ui.container.Tab
        DefineSimulationParametersPanel  matlab.ui.container.Panel
        FinishTimesEditFieldLabel       matlab.ui.control.Label
        FinishTimesEditField            matlab.ui.control.NumericEditField
        EnterJointTorquesasaVectorT1T2TNLabel_2  matlab.ui.control.Label
        EnterJointTorquesasaVectorT1T2TNLabel_3  matlab.ui.control.Label
        q0degormEditFieldLabel          matlab.ui.control.Label
        q0degormEditField               matlab.ui.control.EditField
        q0_dotradsormsEditFieldLabel    matlab.ui.control.Label
        q0_dotradsormsEditField         matlab.ui.control.EditField
        SimulateJointDynamicsBasedonInputTorqueLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        DefineJointTorquesPanel         matlab.ui.container.Panel
        UserInputEditFieldLabel         matlab.ui.control.Label
        UserInputEditField              matlab.ui.control.EditField
        EnterJointTorquesasaVectorT1T2TNLabel  matlab.ui.control.Label
        MethodofDefiningTorqueLabel     matlab.ui.control.Label
        MethodofDefiningTorqueDropDown  matlab.ui.control.DropDown
        IndirectForceControlComplianceTab  matlab.ui.container.Tab
        SimulateButton                  matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        tfEditFieldLabel                matlab.ui.control.Label
        tfEditField                     matlab.ui.control.NumericEditField
        xd6x1columnvectorEditFieldLabel  matlab.ui.control.Label
        xd6x1columnvectorEditField      matlab.ui.control.EditField
        he6x1columnvectorEditFieldLabel  matlab.ui.control.Label
        he6x1columnvectorEditField      matlab.ui.control.EditField
        t00Label                        matlab.ui.control.Label
        IndirectForceControlImpedanceTab  matlab.ui.container.Tab
        kpEditFieldLabel                matlab.ui.control.Label
        kpEditField                     matlab.ui.control.NumericEditField
        xdEditFieldLabel                matlab.ui.control.Label
        xdEditField                     matlab.ui.control.EditField
        he6x1columnvectorEditField_2Label  matlab.ui.control.Label
        he6x1columnvectorEditField_2    matlab.ui.control.EditField
        SimulateButton_2                matlab.ui.control.Button
        dtEditFieldLabel                matlab.ui.control.Label
        dtEditField                     matlab.ui.control.NumericEditField
        xd_ddotEditFieldLabel           matlab.ui.control.Label
        xd_ddotEditField                matlab.ui.control.EditField
        xd_dotEditFieldLabel            matlab.ui.control.Label
        xd_dotEditField                 matlab.ui.control.EditField
        kdEditFieldLabel                matlab.ui.control.Label
        kdEditField                     matlab.ui.control.NumericEditField
        q0EditFieldLabel                matlab.ui.control.Label
        q0EditField                     matlab.ui.control.EditField
        MdDiagonalElementsEditFieldLabel  matlab.ui.control.Label
        MdDiagonalElementsEditField     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        % Comments show defintions of variables used throughout the code.
        % These are accessed with "app.VARIABLENAME". 
        % N is the number of links. 
        % Variables representing physical constants are in SI base
        % units.
        
        % Variables used throughout the code:
        arm_dh = [];    % 4 x N matrix of DH parameters in the order [theta,d,a,alpha]
        arm_dh_sym = [];    % 4 x N matrix of DH parameters in the order [theta,d,a,alpha] but symbolic variables/numbers (used in equations of motion)
        linkLengths = []; % N x 1 vector of link lengths, in meters
        jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
        armLinks = {};  % 1 x N array of Link objects from robotics toolbox *NOTE: DOESN'T CONTAIN DYNAMIC PROPERTIES LIKE I, Jm. Those are in app.ARM.links(i).Jm etc.
        ARM; % Robot arm, SerialLink object
        jointLimits = [];  % N x 2 array of joint limits (min, max)
        linkMasses = [];  % N x 1 array of masses for each link, in kg
        motorMasses = []; % N x 1 array of motor masses, in kg
        motorInertias = []; % N x 1 array of motor inertias, in kg*m^2
        gearRatios = [];    % N x 1 array of gear ratios, unitless
        jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
        linkInertias = {};  % 1 x N array, each entry contains a 3 x 3 inertia matrix in kg*m^2
        g0 = [0;0;-9.81];   % Gravitational acceleration in [x;y;z], m/s^2
        frictionCoeffs; % 1 x N cell array, each entry contains a 3 x 1 vector of friction coefficients B, fc-, and fc+ for joint i
        
        % Variables used in the DH parameters section only
        jointi = 1; % Counter used to keep track of which joint is being edited in arm creation
        armTable;   % A table object used to display DH parameters
        jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
        offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
    end
    
    methods (Access = private)
        % Functions used throughout the app.   
        
        % Used for finding DH parameters automatically
        function [dhi] = DHFinder(app,Z,U,LinkLength)
            %{
            Inputs:
            Z: Z axis of joint i, 1 x 3
            U: direction of link i-1 w.r.t base frame 1 x 3
            LinkLength: Length of the link
            
            Outputs:
            dhi: a collection of dh parameters for this joint i [theta d a
            alpha]
            %}
            
            %Normalize Z and U
            for i=1:size(Z,1)
                Z(i,:)=Z(i,:)/norm(Z(i,:));
                U(i,:)=U(i,:)/norm(U(i,:));
            end
            
            %Find X
            X=zeros(size(Z,1),3);
            for i=1:size(Z,1)
                if i==1
                    X(i,:)=cross([0,0,1],Z(i,:));
                else
                    X(i,:)=cross(Z(i-1,:),Z(i,:));
                end
                if X(i,:)==[0,0,0]
                    if i==1
                        X(i,:)=[0,0,1];
                    else
                        X(i,:)=X(i-1,:);
                    end
                end
            end
            
            %Find O for q=[0]
            O=zeros(size(Z,1),3);
            for i=1:size(Z,1)
                if i==1
                    O(i,:)=U(i,:)*LinkLength(i);
                else
                    O(i,:)=O(i-1,:)+U(i,:)*LinkLength(i);
                end
            end
            
            %Find a
            a=zeros(size(Z,1),1);
            for i=1:size(Z,1)
                P=(X(i,:)'*X(i,:))/(X(i,:)*X(i,:)');
                if i==1
                    Pb=P*O(i,:)';
                else
                    Pb=P*(O(i,:)-O(i-1,:))';
                end
                a(i)=norm(Pb);
            end
            
            %Find d
            d=zeros(size(Z,1),1);
            for i=1:size(Z,1)
                if i==1
                    Zi1=[0 0 1];
                    P=(Zi1'*Zi1)/(Zi1*Zi1');
                    Pb=P*O(i,:)';
                else
                    P=(Z(i-1,:)'*Z(i-1,:))/(Z(i-1,:)*Z(i-1,:)');
                    Pb=P*(O(i,:)-O(i-1,:))';
                end
                d(i)=norm(Pb);
            end
            
            %Find alpha and theta
            alpha=zeros(size(Z,1),1);
            theta=zeros(size(Z,1),1);
            for i=1:size(Z,1)
                if i==1
                    alpha(i)=acos(dot([0 0 1],Z(i,:)));
                    theta(i)=acos(dot([1 0 0],X(i,:)));
                else
                    alpha(i)=acos(dot(Z(i-1,:),Z(i,:)));
                    theta(i)=acos(dot(X(i-1,:),X(i,:)));
                end
            end
            dhi=[theta d a alpha];
            for i=1:size(dhi,1)
                for j=1:size(dhi,2)
                    if isnan(dhi(i,j))
                      dhi(i,j)=0;  
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; clear all; close all
            % Run robotics toolbox startup file
            startup_rvc
        end

        % Button pushed function: AddAboveJointtoArmButton
        function AddAboveJointtoArmButtonPushed(app, event)
            % Get values
            LinkLength = app.LinkLengthmEditField.Value; % Get link length
            dhi = [app.thetaEditField.Value,app.dEditField.Value,app.aEditField.Value,app.alphadegreesEditField.Value*pi/180]; % Get DH parameters
            motorInertia = app.MotorInertiakgm2EditField.Value;
            gearRatio = app.GearRatioEditField.Value;
            linkInertia = [app.EditField.Value,app.EditField_4.Value,app.EditField_7.Value;app.EditField_2.Value,app.EditField_5.Value,app.EditField_8.Value;app.EditField_3.Value,app.EditField_6.Value,app.EditField_9.Value];  % 3 x 3 matrix
            linkMass = app.LinkMasskgEditField.Value;
            motorMass = app.MotorMasskgEditField.Value;
            qlim = [app.JointLimitminEditField.Value,app.JointLimitmaxEditField.Value];
            if app.JointTypeButtonGroup.SelectedObject == app.RevoluteButton
                qlim = qlim*pi/180 % Convert joint limits to radians
            end
            frictionCoeffi = [app.FrictionCoefficientBEditField.Value;app.FrictionCoefficientfcEditField.Value;app.FrictionCoefficientfcEditField2.Value]; % [B,fc-,fc+]
            
            % Update app values
            app.linkLengths = [app.linkLengths;LinkLength]; % Update link lengths
            app.jointsTable(app.jointi) = {num2str(app.jointi)};  % Update row labels (joints) in table
            app.arm_dh = [app.arm_dh;dhi];  % Update arm dh parameters
            app.arm_dh_sym = sym(app.arm_dh);
            app.linkMasses = [app.linkMasses;linkMass];
            app.motorMasses = [app.motorMasses;motorMass];
            app.motorInertias = [app.motorInertias;motorInertia];
            app.gearRatios = [app.gearRatios;gearRatio];
            app.linkInertias{app.jointi} = linkInertia;
            app.jointLimits = [app.jointLimits;qlim];
            app.frictionCoeffs{app.jointi} = frictionCoeffi;
            
            % Read radio button joint type value, convert to a string,
            % append onto list of joint types
            app.jointTypes = [app.jointTypes;convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text)];
            
            % Update boolean vector that contains joint types as 0 or 1
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.jointTypesBool(app.jointi) = 0;
            else
                   app.jointTypesBool(app.jointi) = 1;
            end
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Update the offset (dh parameter value for the joint variable)
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.offset(app.jointi) = theta(app.jointi);
            else
                   app.offset(app.jointi) = d(app.jointi);
            end
            
            % Create a Link object for this link
            linki = Link([dhi,app.jointTypesBool(app.jointi)]);
            app.armLinks{app.jointi} = linki;   % add to app.armLinks
            
            % Combine all the current links into an arm (Links method):
            app.ARM = SerialLink([app.armLinks{1:end}]);
            
%             % Combine all the current links into an arm (DH Parameters method):
%             app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset');
            
            % Apply link and motor properties to the links in the arm as well as offset and joint type
            % because Link() won't take them directly >:( :
            for i = 1:length(app.linkLengths)
                app.ARM.links(i).offset = app.offset(app.jointi);
                app.ARM.links(i).m = app.linkMasses(i);
                app.ARM.links(i).Jm = app.motorInertias(i);
                app.ARM.links(i).I = app.linkInertias{1};
                app.ARM.links(i).G = app.gearRatios(i);
                app.ARM.links(i).qlim = app.jointLimits(i,:);
                app.ARM.links(i).B = app.frictionCoeffs{i}(1);
                app.ARM.links(i).Tc = [app.frictionCoeffs{i}(3),app.frictionCoeffs{i}(2)];
            end
            
            % Update app.armLinks
            app.armLinks{app.jointi} = linki;
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = app.jointi + 1;    % Increment joint
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SimulationTab;   % Change to "Simulation" when you press finished on arm creation
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ArmDefinitionTab;    % Change to "Arm Definition" when you first start
        end

        % Button pushed function: AddAboveJointtoArmButton_2
        function AddAboveJointtoArmButton_2Pushed(app, event)
            % Find DH Parameters for user
            
            % Get values
            LinkLength = app.LinkLengthmEditField.Value; % Get link length
            Z = [app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value];
            U = [app.XEditField_2.Value,app.YEditField_2.Value,app.ZEditField_2.Value];
            motorInertia = app.MotorInertiakgm2EditField.Value;
            gearRatio = app.GearRatioEditField.Value;
            linkInertia = [app.EditField.Value,app.EditField_4.Value,app.EditField_7.Value;app.EditField_2.Value,app.EditField_5.Value,app.EditField_8.Value;app.EditField_3.Value,app.EditField_6.Value,app.EditField_9.Value];  % 3 x 3 matrix
            linkMass = app.LinkMasskgEditField.Value;
            motorMass = app.MotorMasskgEditField.Value;
            qlim = [app.JointLimitminEditField.Value,app.JointLimitmaxEditField.Value];
            if app.JointTypeButtonGroup.SelectedObject == app.RevoluteButton
                qlim = qlim*pi/180 % Convert joint limits to radians
            end
            frictionCoeffi = [app.FrictionCoefficientBEditField.Value;app.FrictionCoefficientfcEditField.Value;app.FrictionCoefficientfcEditField2.Value]; % [B,fc-,fc+]
            
            % Use "DHFinder" function to find the DH Parameters:
            dhi = app.DHFinder(Z,U,LinkLength);
            
            % Update app values
            app.linkLengths = [app.linkLengths;LinkLength]; % Update link lengths
            app.jointsTable(app.jointi) = {num2str(app.jointi)};  % Update row labels (joints) in table
            app.arm_dh = [app.arm_dh;dhi];  % Update arm dh parameters
            app.linkMasses = [app.linkMasses;linkMass];
            app.motorMasses = [app.motorMasses;motorMass];
            app.motorInertias = [app.motorInertias;motorInertia];
            app.gearRatios = [app.gearRatios;gearRatio];
            app.linkInertias{app.jointi} = linkInertia;
            app.jointLimits = [app.jointLimits;qlim];
            app.frictionCoeffs{app.jointi} = frictionCoeffi;
            
            % Read radio button joint type value, convert to a string,
            % append onto list of joint types
            app.jointTypes = [app.jointTypes;convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text)];
            
            % Update boolean vector that contains joint types as 0 or 1
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.jointTypesBool(app.jointi) = 0;
            else
                   app.jointTypesBool(app.jointi) = 1;
            end
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Update the offset (dh parameter value for the joint variable)
            if convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text) == "Revolute"
                   app.offset(app.jointi) = theta(app.jointi);
            else
                   app.offset(app.jointi) = d(app.jointi);
            end
            
            % Create a Link object for this link
            linki = Link([dhi,app.jointTypesBool(app.jointi)]);
            app.armLinks{app.jointi} = linki;   % add to app.armLinks
            
            % Combine all the current links into an arm (Links method):
            app.ARM = SerialLink([app.armLinks{1:end}]);
            
%             % Combine all the current links into an arm (DH Parameters method):
%             app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset');
            
            % Apply link and motor properties to the links in the arm as well as offset
            for i = 1:length(app.linkLengths)
                app.ARM.links(i).offset = app.offset(app.jointi);
                app.ARM.links(i).m = app.linkMasses(i);
                app.ARM.links(i).Jm = app.motorInertias(i);
                app.ARM.links(i).I = app.linkInertias{1};
                app.ARM.links(i).G = app.gearRatios(i);
                app.ARM.links(i).qlim = app.jointLimits(i,:);
                app.ARM.links(i).B = app.frictionCoeffs{i}(1);
                app.ARM.links(i).Tc = [app.frictionCoeffs{i}(3),app.frictionCoeffs{i}(2)];
            end
            
            % Update app.armLinks
            app.armLinks{app.jointi} = linki;
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = app.jointi + 1;    % Increment joint
        end

        % Button pushed function: ClearArmandStartOverButton
        function ClearArmandStartOverButtonPushed(app, event)
            % Clears all the variables defined during arm creation. Also
            % resets the table
            
            app.arm_dh = [];    % 4 x N matrix of DH parameters in the order [theta,d,a,alpha]
            app.arm_dh_sym = [];    % 4 x N matrix of DH parameters in the order [theta,d,a,alpha] but symbolic variables/numbers (used in equations of motion)
            app.linkLengths = []; % N x 1 vector of link lengths, in meters
            app.jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
            app.armLinks = {};  % 1 x N array of Link objects from robotics toolbox *NOTE: DOESN'T CONTAIN DYNAMIC PROPERTIES LIKE I, Jm. Those are in app.ARM.links(i).Jm etc.
            app.ARM; % Robot arm, SerialLink object
            app.jointLimits = [];  % N x 2 array of joint limits (min, max)
            app.linkMasses = [];  % N x 1 array of masses for each link, in kg
            app.motorMasses = []; % N x 1 array of motor masses, in kg
            app.motorInertias = []; % N x 1 array of motor inertias, in kg*m^2
            app.gearRatios = [];    % N x 1 array of gear ratios, unitless
            app.jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
            app.linkInertias = {};  % 1 x N array, each entry contains a 3 x 3 inertia matrix in kg*m^2
            app.g0 = [0;0;-9.81];   % Gravitational acceleration in [x;y;z], m/s^2
            app.jointi = 1;
            app.jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
            app.offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
            app.frictionCoeffs = {};
            
            % Empty the table
            JointType = [];
            Length = [];
            a = [];
            alpha = [];
            d = [];
            theta = [];
            app.armTable = table(JointType,Length,a,alpha,d,theta,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
        end

        % Button pushed function: PlotRobotArmButton
        function PlotRobotArmButtonPushed(app, event)
            % Plot robot arm at initial state (all joint variables = 0)
            q = zeros(1,size(app.arm_dh,1));
            l = sum(app.linkLengths);
            W = [-l,l,-l,l,-l,l];
            app.ARM.plot(q,'workspace',W)
        end

        % Button pushed function: DriveRobotArmButton
        function DriveRobotArmButtonPushed(app, event)
            % Setup plot like normal then add teach()
            q = zeros(1,size(app.arm_dh,1));
            l = sum(app.linkLengths);
            W = [-l,l,-l,l,-l,l];
            app.ARM.plot(q,'workspace',W)
            teach(app.ARM)
        end

        % Button pushed function: LinkPlanarButton
        function LinkPlanarButtonPushed(app, event)
            % Default Arm Configuration: 3 Link Planar Arm
            % Defines a 3 link planar arm with arm length 1
            
            % Clears all the variables defined during arm creation. Also
            % resets the table
                       
            app.arm_dh = [];    % 4 x N matrix of DH parameters in the order [a,alpha,d,theta]
            app.linkLengths = []; % N x 1 vector of link lengths, in meters
            app.jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
            app.armLinks = {};  % 1 x N array of Link objects from robotics toolbox
            app.jointi = 1; % Counter used to keep track of which joint is being edited in arm creation
            app.jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
            app.jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
            app.offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
            app.jointLimits = [];
            
            % Empty the table
            JointType = [];
            Length = [];
            a = [];
            alpha = [];
            d = [];
            theta = [];
            app.armTable = table(JointType,Length,a,alpha,d,theta,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            
            % Now, define the 3 link planar arm
            % Update app values
            app.linkLengths = [1;1;1]; % Link Lengths are all 1
            app.jointsTable = {'1','2','3'};  % Update row labels (joints) in table
            app.arm_dh = [0,0,1,0;0,0,1,0;0,0,1,0];  % Update arm dh parameters
            app.arm_dh_sym = sym(app.arm_dh);
            app.linkMasses = [50;50;50];
            app.motorMasses = [1;1;1];
            app.motorInertias = [0.01;0.01;0.01];
            app.gearRatios = [90;90;90];
            linkInertia = diag([20,20,20]);
            app.linkInertias{1} = linkInertia;
            app.linkInertias{2} = linkInertia;
            app.linkInertias{3} = linkInertia;
            app.frictionCoeffs{1} = [0;0;0];
            app.frictionCoeffs{2} = [0;0;0];
            app.frictionCoeffs{3} = [0;0;0];
            
            % Update joint types
            app.jointTypes = ["Revolute";"Revolute";"Revolute"];
            
            % Update boolean vector that contains joint types as 0 or 1
            app.jointTypesBool = [0,0,0];
            
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Offset is 0 for the 3 link planar arm
            app.offset = [0,0,0];
            
            % Define joint limits:
            app.jointLimits = [-pi,pi;-pi,pi;-pi,pi];
            
            % Create 3 links:
            link1 = Link([app.arm_dh(1,:),app.jointTypesBool(1)]);
            link2 = Link([app.arm_dh(2,:),app.jointTypesBool(2)]);
            link3 = Link([app.arm_dh(3,:),app.jointTypesBool(3)]);
            app.armLinks{1} = link1;
            app.armLinks{2} = link2;
            app.armLinks{3} = link3;
            
            % Combine all the current links into an arm (Links method):
            app.ARM = SerialLink([link1,link2,link3]);
            
%             % Combine all the current links into an arm:
%             app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset','qlim',app.jointLimits,'name','Your Robot');
%             
            % Apply link and motor properties to the links in the arm:
            for i = 1:length(app.linkLengths)
                app.ARM.links(i).offset = app.offset(i);
                app.ARM.links(i).m = app.linkMasses(i);
                app.ARM.links(i).Jm = app.motorInertias(i);
                app.ARM.links(i).I = app.linkInertias{1};
                app.ARM.links(i).G = app.gearRatios(i);
                app.ARM.links(i).qlim = app.jointLimits(i,:);
                app.ARM.links(i).B = app.frictionCoeffs{i}(1);
                app.ARM.links(i).Tc = [app.frictionCoeffs{i}(3),app.frictionCoeffs{i}(2)];
            end
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = 3;    % Increment joint
            
        end

        % Button pushed function: LinearGrasperButton
        function LinearGrasperButtonPushed(app, event)
            % Default Arm Configuration: Linear Grasper
            % Defines a "linear grasper" (2 revolute joints and 1
            % prismatic)
            
            % Clears all the variables defined during arm creation. Also
            % resets the table
                       
            app.arm_dh = [];    % 4 x N matrix of DH parameters in the order [a,alpha,d,theta]
            app.linkLengths = []; % N x 1 vector of link lengths, in meters
            app.jointTypes = [];    % N x 1 string array containing "Revolute" or "Prismatic" for each joint
            app.armLinks = {};  % 1 x N array of Link objects from robotics toolbox
            app.jointi = 1; % Counter used to keep track of which joint is being edited in arm creation
            app.jointsTable = {};       % 1 x N cell array of link #s used to label the DH table
            app.jointTypesBool = [];    % 1 x N vector of 0s (Revolute) or 1s (Prismatic) used in defining arm with SerialLink command  (gets transposed when it's eventually used)
            app.offset = [];    % 1 x N vector that contains the offset (DH parameter value for the joint variable for that joint) (gets transposed when it's eventually used)
            
            % Empty the table
            JointType = [];
            Length = [];
            a = [];
            alpha = [];
            d = [];
            theta = [];
            app.armTable = table(JointType,Length,a,alpha,d,theta,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            
            % Now, define the linear grasper
            % Update app values
            app.linkLengths = [1;1;1]; % Link Lengths are all 1
            app.jointsTable = {'1','2','3'};  % Update row labels (joints) in table
            app.arm_dh = [0,0,1,pi/2;0,0,1,pi/2;0,0,1,0];  % Update arm dh parameters
            app.arm_dh_sym = sym(app.arm_dh);
            app.linkMasses = [50;50;50];
            app.motorMasses = [1;1;1];
            app.motorInertias = [0.01;0.01;0.01];
            app.gearRatios = [90;90;90];
            linkInertia = diag([20,20,20]);
            app.linkInertias{1} = linkInertia;
            app.linkInertias{2} = linkInertia;
            app.linkInertias{3} = linkInertia;
            app.frictionCoeffs{1} = [0;0;0];
            app.frictionCoeffs{2} = [0;0;0];
            app.frictionCoeffs{3} = [0;0;0];
            
            % Update joint types
            app.jointTypes = ["Revolute";"Revolute";"Prismatic"];
            
            % Update boolean vector that contains joint types as 0 or 1
            app.jointTypesBool = [0,0,1];
            
            
            % Extract parameters to put in table
            a = app.arm_dh(:,3);
            alpha = app.arm_dh(:,4);
            d = app.arm_dh(:,2);
            theta = app.arm_dh(:,1);
            Length = app.linkLengths;
            JointType = app.jointTypes; % Names of joints so they can go in the table
            
            % Offset is pi/2 for second revolute joint
            app.offset = [0,pi/2,0];
            
            % Define joint limits:
            app.jointLimits = [-pi/2,pi/2;-pi/2,pi/2;0,1.5];
           
            % Create 3 links:
            link1 = Link([app.arm_dh(1,:),app.jointTypesBool(1)]);
            link2 = Link([app.arm_dh(2,:),app.jointTypesBool(2)]);
            link3 = Link([app.arm_dh(3,:),app.jointTypesBool(3)]);
            app.armLinks{1} = link1;
            app.armLinks{2} = link2;
            app.armLinks{3} = link3;
            
            % Combine all the current links into an arm (Links method):
            app.ARM = SerialLink([link1,link2,link3]);
            
%             % Combine all the current links into an arm:
%             app.ARM = SerialLink([app.arm_dh,app.jointTypesBool'],'offset',app.offset','qlim',app.jointLimits);
            
            % Apply link and motor properties to the links in the arm:
            for i = 1:length(app.linkLengths)
                app.ARM.links(i).offset = app.offset(i);
                app.ARM.links(i).m = app.linkMasses(i);
                app.ARM.links(i).Jm = app.motorInertias(i);
                app.ARM.links(i).I = app.linkInertias{1};
                app.ARM.links(i).G = app.gearRatios(i);
                app.ARM.links(i).qlim = app.jointLimits(i,:);
                app.ARM.links(i).B = app.frictionCoeffs{i}(1);
                app.ARM.links(i).Tc = [app.frictionCoeffs{i}(3),app.frictionCoeffs{i}(2)];
            end
            
            % Update table
            app.armTable = table(JointType,Length,theta,d,a,alpha,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
            
            app.jointi = 3;    % Increment joint
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            % Updates a symmetric part of the inertia matrix
            value = app.EditField_4.Value;
            app.EditField_2.Value = value;
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            % Updates a symmetric part of the inertia matrix
            value = app.EditField_7.Value;
            app.EditField_3.Value = value;
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            % Updates a symmetric part of the inertia matrix
            value = app.EditField_8.Value;
            app.EditField_6.Value = value;
        end

        % Button pushed function: SimulateSystemDynamicsButton
        function SimulateSystemDynamicsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SystemDynamicsTab;
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            % Test ICs:
%             q0=[-50;90;30]*pi/180; q_dot0=[0;0;0];

            % Retrieve user inputs:
            % (Note: To input a vector in app designer, we need to receive a
            % string because numeric input fields can only take scalars. So
            % we convert the string to numbers. This breaks if they use
            % non-numeric characters like "[" so there may be a better
            % way.)
            q0 = app.q0degormEditField.Value;
            q0 = str2double(strsplit(q0,','))*pi/180;   % Convert to rads bc we ask for it in degrees
            q_dot0 = app.q0_dotradsormsEditField.Value;
            q_dot0 = str2double(strsplit(q_dot0,','));
            final_time = app.FinishTimesEditField.Value;
            robot = app.ARM;
            
            % Choose which torque input to use and perform dynamics simulation w/o friction:
            if app.MethodofDefiningTorqueDropDown.Value == "Enter Constant Values"
                [time,q,q_dot] = robot.nofriction.fdyn(final_time,@torqfunConstant,q0,q_dot0);
            elseif app.MethodofDefiningTorqueDropDown.Value == "User Provided Function File"
                functionName = app.UserInputEditField.Value;
                torqfun = str2func(functionName);
                [time,q,q_dot] = robot.nofriction.fdyn(final_time,torqfun,q0,q_dot0);
            elseif app.MethodofDefiningTorqueDropDown.Value == "Enter Function by Hand"
                [time,q,q_dot] = robot.nofriction.fdyn(final_time,@torqfunUser,q0,q_dot0);
            end    

            % Get joint accelerations
            for i=1:length(q)
                q_ddot(i,:) = robot.accel(q(i,:), q_dot(i,:), [0 0 0]);
            end
  
            % Initialize tiled layout (like a subplot but easier in app
            % designer)
            dynamicsPlots = tiledlayout(app.Panel,4,1);
            title(dynamicsPlots,'Simulation of Joint Dynamics')
            
            % Plot joint position:
            ax1 = nexttile(dynamicsPlots);
            plot(ax1,time,q,'Linewidth',2)
            legend(ax1,'$q_1$','$q_2$','$q_3$','Interpreter','latex')
            xlabel(ax1,'time (s)');
            ylabel(ax1,'$q(t)$ (rad)','Interpreter','latex');
            title(ax1,'$q(t)$ vs time','Interpreter','latex')
            grid(ax1,"on")
            
            % Plot joint velocity:
            ax2 = nexttile(dynamicsPlots);
            plot(ax2,time,q_dot,'Linewidth',2)
            legend(ax2,'$\dot{q_1}$','$\dot{q_2}$','$\dot{q_3}$','Interpreter','latex');
            xlabel(ax2,'time (s)');
            ylabel(ax2,'$\dot{q(t)}$ (rad/s)','Interpreter','latex');
            title(ax2,'$\dot{q}(t)$ vs time','Interpreter','latex')
            grid(ax2,"on")
            
            % Plot joint acceleration:
            ax3 = nexttile(dynamicsPlots);
            plot(ax3,time,q_ddot,'Linewidth',2)
            legend(ax3,'$\ddot{q_1}$','$\ddot{q_2}$','$\ddot{q_3}$','Interpreter','latex');
            xlabel(ax3,'time (s)');
            ylabel(ax3,'$\ddot{q}(t)$ $(rad/s^2)$','Interpreter','latex');
            title(ax3,'$\ddot{q(t)}$ vs time','Interpreter','latex')            
            grid(ax3,"on")
            
%            Put the robot simulation in the app designer window - this
%            code isn't working...
%             nexttile(dynamicsPlots)
%             ax4 = subplot(1,1,1)
%             robot.plot(q)
            
            
            function tau = torqfunConstant(robot, t, q, qd, varargin)
                        % Turns user constant torque input into a function used by fdyn
                        % To input a vector in app designer, we need to receive a
                        % string because numeric input fields can only take scalars. So
                        % we convert the string to numbers. This breaks if they use
                        % non-numeric characters like "[" so there may be a better way.
                        torque = app.UserInputEditField.Value;
                        tau = str2double(strsplit(torque,','));            
            end
            
            function tau = torqfunUser(robot,t,q,qd,varargin)
                fcn = app.UserInputEditField.Value;
                fcn = strsplit(fcn,',')
                for i = 1:length(fcn)
                    tau(i) = eval(fcn{i});
                end
                
            end
        end

        % Button pushed function: PrintEquationsofMotionButton
        function PrintEquationsofMotionButtonPushed(app, event)
            %% Finds equations of motion (B,C,G matrices using EOMFinder
            % Define variables that take relevant app data and add to
            % function (mostly just for clarity)
            Arm = app.ARM;
            Im = app.motorInertias;
            Il = app.linkInertias;
            Mm = app.motorMasses;
            Ml = app.linkMasses;
            J = app.jointTypesBool;
            k = app.gearRatios; 
            g0eom = app.g0;
            dh_syms = app.arm_dh_sym;
            
            % Define symbolic variables (because defining inside EOMFinder
            % caused issues
            syms he_x he_y he_z T_x T_y T_z
            he = [he_x,he_y,he_z,T_x,T_y,T_z]';
            assume(he,'real');
            
            [B,C,G,Je,Fpos,Fneg,Arm,Full_EOMpos,Full_EOMneg] = EOMFinder(Arm,Im,Il,Mm,Ml,J,k,g0eom,dh_syms,he)
        end

        % Selection changed function: JointTypeButtonGroup
        function JointTypeButtonGroupSelectionChanged(app, event)
            % Change default joint limits based on which joint is selected
            selectedButton = app.JointTypeButtonGroup.SelectedObject;
            if selectedButton == app.RevoluteButton
                % Revolute: -pi to pi is default
                app.JointLimitminEditField.Value = -180;
                app.JointLimitmaxEditField.Value = 180;
            else
                % Prismatic: 0 to 1 is default
                app.JointLimitminEditField.Value = 0;
                app.JointLimitmaxEditField.Value = 1;
            end
        end

        % Button pushed function: ComplianceControlButton
        function ComplianceControlButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.IndirectForceControlComplianceTab;
        end

        % Button pushed function: ImpedanceControlButton
        function ImpedanceControlButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.IndirectForceControlImpedanceTab;
        end

        % Value changed function: MethodofDefiningTorqueDropDown
        function MethodofDefiningTorqueDropDownValueChanged(app, event)
            value = app.MethodofDefiningTorqueDropDown.Value;
            if value == "User Provided Function File"
                app.EnterJointTorquesasaVectorT1T2TNLabel.Text = ("Enter the name of your function." + newline + "See user manual for function requirements.");
            elseif value == "Enter Function by Hand"
                app.EnterJointTorquesasaVectorT1T2TNLabel.Text = ("Enter the functions describing joint torques as a" +newline + "comma separated list of expressions of t," + newline + "such as t^2,t+1,3,...");
            elseif value == "Enter Constant Values"
                app.EnterJointTorquesasaVectorT1T2TNLabel.Text = ("Enter Joint Torques as T1,T2,....TN," + newline + "with commas separating torques");
            end
        end

        % Button pushed function: SimulateButton
        function Comp_control(app, event)
            he=app.he6x1columnvectorEditField.Value;
            xd=app.xd6x1columnvectorEditField.Value;
            tf=app.tfEditField.Value;
            Arm = app.ARM;
            Im = app.motorInertias;
            Il = app.linkInertias;
            Mm = app.motorMasses;
            Ml = app.linkMasses;
            J = app.jointTypesBool;
            k = app.gearRatios; 
            g0eom = app.g0;
            dh_syms = app.arm_dh_sym;
            
            %get friction coefficients
            n=size(dh_syms,1);
            FB=zeros(n,1);
            Fpos=zeros(n,1);
            Fneg=zeros(n,1);
            for i=1:n
            FB(i) = Arm.links(i).B; %nx1 vector
            Fpos(i)= Arm.links(i).Tc(:,1); %nx1 vector
            Fneg(i)= Arm.links(i).Tc(:,2); %nx1 vector
            end
            
            %convert xd and he to vectors
            b=erase(xd,{'[',']'});
            a=split(b,{',',';'});
            xd = str2double(a);   %now you have a 6x1 column vector
            b=erase(he,{'[',']'});
            a=split(b,{',',';'});
            he = str2double(a);   %now you have a 6x1 column vector
            
            xe=compliance_control(he,xd,Arm,Im,Il,Mm,Ml,J,k,g0eom,dh_syms, tf, FB, Fpos, Fneg)
            
%             % Initialize tiled layout (like a subplot but easier in app
%             % designer)
%             dynamicsPlots = tiledlayout(app.Panel,1,1);
%             title(dynamicsPlots,'Simulation of Compliance Control')
%             
%             % Plot joint position:
%             ax1 = nexttile(dynamicsPlots);
%             plot(ax1,time,xd,'Linewidth',2)
%             legend(ax1,'$xd$','$xe$','$he$','Interpreter','latex')
%             xlabel(ax1,'time (s)');
%             ylabel(ax1,'$Position$','Interpreter','latex');
%             title(ax1,'$Position$ vs time','Interpreter','latex')
%             grid(ax1,"on")


        end

        % Button pushed function: SimulateButton_2
        function SimulateButton_2Pushed(app, event)
            % First, run EOMFinder to get Bs, Gs, etc.
            %% Finds equations of motion (B,C,G matrices using EOMFinder
            % Define variables that take relevant app data and add to
            % function (mostly just for clarity)
            Arm = app.ARM;
            Im = app.motorInertias;
            Il = app.linkInertias;
            Mm = app.motorMasses;
            Ml = app.linkMasses;
            J = app.jointTypesBool;
            k = app.gearRatios; 
            g0eom = app.g0;
            dh_syms = app.arm_dh_sym;
            
            % Define symbolic variables (because defining inside EOMFinder
            % caused issues
            syms he_x he_y he_z T_x T_y T_z
            he = [he_x,he_y,he_z,T_x,T_y,T_z]';
            assume(he,'real');
            
            [Bq,Cq,Gq,Je,Fqpos,Fqneg,Arm,Full_EOMpos,Full_EOMneg] = EOMFinder(Arm,Im,Il,Mm,Ml,J,k,g0eom,dh_syms,he);
            
            % Define inputs for impedanceControl
            syms t;
            numLinks = length(app.linkLengths);
            xdFunc = str2sym(strsplit(convertCharsToStrings(app.xdEditField.Value),','));
            xd_dotFunc = diff(xdFunc,t);
            xd_ddotFunc = diff(xd_dotFunc,t)
%             xd_dotFunc = strsplit(convertCharsToStrings(app.xd_dotEditField.Value),',');
%             xd_ddotFunc = strsplit(convertCharsToStrings(app.xd_ddotEditField.Value),',');
            kp = app.kpEditField.Value;
            kd = app.kdEditField.Value;
            Md = str2num(app.MdDiagonalElementsEditField.Value);
            Md = diag(Md);
            q0 = app.q0EditField.Value;
            q0 = (str2double(strsplit(q0,','))*pi/180)';
            heFunc = str2sym(strsplit(convertCharsToStrings(app.he6x1columnvectorEditField.Value),','))
            dt = app.dtEditField.Value;
            Arm = app.ARM;
            [t_Data,x_Data] = impedanceControl(numLinks,xdFunc',xd_dotFunc',xd_ddotFunc',kp,kd,Md,q0,heFunc',dt,Arm,Bq,Cq,Gq,Fqpos,Fqneg);
            
            test = 3;
% ************************** Simulink Code :(
%             disp("%%%%%%%From GUI%%%%%%")
%             he=app.he6x1columnvectorEditField.Value
%             xd=app.xd6x1columnvectorEditField.Value
%             t0=app.t0EditField.Value
%             tf=app.tfEditField.Value
%             Arm = app.ARM
%             %%%%%%%%%%%%
% 
%             Im = app.motorInertias
%             Il = app.linkInertias
%             Mm = app.motorMasses
%             Ml = app.linkMasses
%             J = app.jointTypesBool
%             k = app.gearRatios
%             g0eom = app.g0
%             dh_syms = app.arm_dh_sym
%             %get friction coefficients
%             n=size(dh_syms,1);
%             FB=zeros(n,1);
%             Fpos=zeros(n,1);
%             Fneg=zeros(n,1);
%             for i=1:n
%             FB(i) = Arm.links(i).B; %nx1 vector
%             Fpos(i)= Arm.links(i).Tc(:,1); %nx1 vector
%             Fneg(i)= Arm.links(i).Tc(:,2); %nx1 vector
%             end
%             disp("%%%%%%%From GUI%%%%%%")
%             %convert xd and he to vectors
%             b=erase(xd,{'[',']'});
%             a=split(b,{',',';'});
%             xd = str2double(a);   %now you have a 6x1 column vector
%             b=erase(he,{'[',']'});
%             a=split(b,{',',';'});
%             he = str2double(a);   %now you have a 6x1 column vector
%             %%
%             % Define symbolic variables (because defining inside EOMFinder
%             % caused issues
%             
%             %syms he_x he_y he_z T_x T_y T_z
%             %he = [he_x,he_y,he_z,T_x,T_y,T_z]';
%             
%             [B,C,G] = EOMFinder(Arm,Im,Il,Mm,Ml,J,k,g0eom,dh_syms)
%             disp("Arm")
%             Arm
%             disp("Ja")
%             Ja = jacob0(Arm, [0 0 0], 'rpy')
%             %jacob0(Arm, [0 0 0], 'rpy')
%             assignin('base','Arm',Arm)
%             assignin('base','Im',Im)
%             assignin('base','Il',Il)
%             assignin('base','Mm',Mm)
%             assignin('base','Ml',Ml)
%             assignin('base','k',k)
%             assignin('base','g0eom',g0eom)
%             assignin('base','FB',FB)
%             assignin('base','Fpos',Fpos)
%             assignin('base','Fneg',Fneg)
%             assignin('base','dh_syms',dh_syms)
%             %impedance_control(he,xd,Arm,Im,Il,Mm,Ml,J,k,g0eom,dh_syms,t0, tf, FB, Fpos, Fneg)
%             timespan=linspace(t0, tf, (tf-t0)*100);
%             arm_dh_=app.arm_dh
% 
%             n=length(Arm.links);
%             for i=1:3
%                 Arm{i} = [Arm.a(i); Arm.alpha(i); Arm.d(i); Arm.theta(i)];
%                 name = sprintf('myArm{%d}',i);
%                 save test2_.mat name;
%             end
%             
%             %save('test2_.mat', 'Arm', 'arm_dh_', 'FB', '-v7.3')
%             sim('test2')
%             % Initialize tiled layout (like a subplot but easier in app
%             % designer)
%             %dynamicsPlots = tiledlayout(app.Panel,1,1);
%             %title(dynamicsPlots,'Simulation of Compliance Control')
%             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome!';
            app.STARTButton = uibutton(app.WelcomeTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ArmDefinitionTab = uitab(app.TabGroup);
            app.ArmDefinitionTab.Title = 'Arm Definition';
            app.EnterLinkandJointPropertiesPanel = uipanel(app.ArmDefinitionTab);
            app.EnterLinkandJointPropertiesPanel.Title = '1) Enter Link and Joint Properties';
            app.JointTypeButtonGroup = uibuttongroup(app.EnterLinkandJointPropertiesPanel);
            app.JointTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JointTypeButtonGroupSelectionChanged, true);
            app.JointTypeButtonGroup.Title = 'Joint Type';
            app.RevoluteButton = uiradiobutton(app.JointTypeButtonGroup);
            app.RevoluteButton.Text = 'Revolute';
            app.RevoluteButton.Value = true;
            app.PrismaticButton = uiradiobutton(app.JointTypeButtonGroup);
            app.PrismaticButton.Text = 'Prismatic';
            app.LinkLengthmEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.LinkLengthmEditFieldLabel.Text = 'Link Length (m)';
            app.LinkLengthmEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.LinkLengthmEditField.Value = 1;
            app.LinkMasskgEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.LinkMasskgEditFieldLabel.Text = 'Link Mass (kg)';
            app.LinkMasskgEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.LinkMasskgEditField.Value = 1;
            app.MotorMasskgEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.MotorMasskgEditFieldLabel.Text = 'Motor Mass (kg)';
            app.MotorMasskgEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.MotorMasskgEditField.Value = 1;
            app.GearRatioEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.GearRatioEditField.Value = 10;
            app.LinkInertiaMatrixPanel = uipanel(app.EnterLinkandJointPropertiesPanel);
            app.LinkInertiaMatrixPanel.Title = 'Link Inertia Matrix';
            app.EditField = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField.Value = 20;
            app.EditField_2 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_5.Value = 20;
            app.EditField_6 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_8 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_9 = uieditfield(app.LinkInertiaMatrixPanel, 'numeric');
            app.EditField_9.Value = 20;
            app.MotorInertiakgm2Label = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.MotorInertiakgm2Label.Text = {'Motor Inertia'; '(kg*m^2)'};
            app.MotorInertiakgm2EditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.MotorInertiakgm2EditField.Value = 0.01;
            app.JointLimitminEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.JointLimitminEditFieldLabel.Text = 'Joint Limit (min)';
            app.JointLimitminEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.JointLimitminEditField.Value = -180;
            app.JointLimitmaxEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.JointLimitmaxEditFieldLabel.Text = 'Joint Limit (max)';
            app.JointLimitmaxEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.JointLimitmaxEditField.Value = 180;
            app.enteranglesindegreesLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.enteranglesindegreesLabel.Text = '(enter angles in degrees)';
            app.FrictionCoefficientBEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.FrictionCoefficientBEditFieldLabel.Text = 'Friction Coefficient ''B''';
            app.FrictionCoefficientBEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.FrictionCoefficientfcEditFieldLabel = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.FrictionCoefficientfcEditFieldLabel.Text = 'Friction Coefficient ''fc-''';
            app.FrictionCoefficientfcEditField = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.FrictionCoefficientfcEditFieldLabel2 = uilabel(app.EnterLinkandJointPropertiesPanel);
            app.FrictionCoefficientfcEditFieldLabel2.Text = 'Friction Coefficient ''fc+''';
            app.FrictionCoefficientfcEditField2 = uieditfield(app.EnterLinkandJointPropertiesPanel, 'numeric');
            app.AddJointstoArmLabel = uilabel(app.ArmDefinitionTab);
            app.AddJointstoArmLabel.Text = 'Add Joints to Arm';
            app.CurrentArmLabel = uilabel(app.ArmDefinitionTab);
            app.CurrentArmLabel.Text = 'Current Arm:';
            app.FinishedButton = uibutton(app.ArmDefinitionTab, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished!';
            app.ClearArmandStartOverButton = uibutton(app.ArmDefinitionTab, 'push');
            app.ClearArmandStartOverButton.ButtonPushedFcn = createCallbackFcn(app, @ClearArmandStartOverButtonPushed, true);
            app.ClearArmandStartOverButton.Text = 'Clear Arm and Start Over';
            app.CurrentArmTable = uitable(app.ArmDefinitionTab);
            app.CurrentArmTable.ColumnName = {'JointType'; 'Length'; 'a'; 'alpha'; 'd'; 'theta'};
            app.CurrentArmTable.RowName = {};
            app.LinkPlanarButton = uibutton(app.ArmDefinitionTab, 'push');
            app.LinkPlanarButton.ButtonPushedFcn = createCallbackFcn(app, @LinkPlanarButtonPushed, true);
            app.LinkPlanarButton.Text = '3 Link Planar';
            app.DefaultArmConfigurationsLabel = uilabel(app.ArmDefinitionTab);
            app.DefaultArmConfigurationsLabel.Text = 'Default Arm Configurations';
            app.Image = uiimage(app.ArmDefinitionTab);
            app.Image.ImageSource = '3LinkArm.png';
            app.LinearGrasperButton = uibutton(app.ArmDefinitionTab, 'push');
            app.LinearGrasperButton.ButtonPushedFcn = createCallbackFcn(app, @LinearGrasperButtonPushed, true);
            app.LinearGrasperButton.Text = 'Linear Grasper';
            app.Image2 = uiimage(app.ArmDefinitionTab);
            app.Image2.ImageSource = 'Manipulator.png';
            app.Label = uilabel(app.ArmDefinitionTab);
            app.Label.Text = '_________________________________________';
            app.Label_2 = uilabel(app.ArmDefinitionTab);
            app.Label_2.Text = '_________________________________________';
            app.EnterorFindDHParametersPanel = uipanel(app.ArmDefinitionTab);
            app.EnterorFindDHParametersPanel.Title = '2) Enter or Find DH Parameters';
            app.EnterDHParametersPanel = uipanel(app.EnterorFindDHParametersPanel);
            app.EnterDHParametersPanel.Title = 'Enter DH Parameters:';
            app.aEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.alphadegreesEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.alphadegreesEditFieldLabel.Text = 'alpha (degrees)';
            app.alphadegreesEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.thetaEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.ORLabel = uilabel(app.EnterorFindDHParametersPanel);
            app.ORLabel.Text = {'|'; '|'; '|'; '|'; '|'; 'OR'; '|'; '|'; '|'; '|'; '|'};
            app.FindDHParametersAutomaticallyPanel = uipanel(app.EnterorFindDHParametersPanel);
            app.FindDHParametersAutomaticallyPanel.Title = 'Find DH Parameters Automatically:';
            app.ZAxisofJointLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.ZAxisofJointLabel.Text = 'Z Axis of Joint';
            app.DirectionofLinki1WRTBaseFrameLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.DirectionofLinki1WRTBaseFrameLabel.Text = {'Direction of Link i-1 '; 'WRT Base Frame'};
            app.XEditFieldLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.XEditField_4Label = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.FindDHParametersAutomaticallyPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.FindDHParametersAutomaticallyPanel, 'numeric');
            app.AddAboveJointtoArmButton_2 = uibutton(app.EnterorFindDHParametersPanel, 'push');
            app.AddAboveJointtoArmButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddAboveJointtoArmButton_2Pushed, true);
            app.AddAboveJointtoArmButton_2.Text = 'Add Above Joint to Arm';
            app.AddAboveJointtoArmButton = uibutton(app.EnterorFindDHParametersPanel, 'push');
            app.AddAboveJointtoArmButton.ButtonPushedFcn = createCallbackFcn(app, @AddAboveJointtoArmButtonPushed, true);
            app.AddAboveJointtoArmButton.Text = 'Add Above Joint to Arm';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationHomePageLabel = uilabel(app.SimulationTab);
            app.SimulationHomePageLabel.Text = 'Simulation - Home Page';
            app.Label_4 = uilabel(app.SimulationTab);
            app.Label_4.Text = '__________________________________________________________________________________';
            app.PlottingPanel = uipanel(app.SimulationTab);
            app.PlottingPanel.Title = 'Plotting';
            app.PlotRobotArmButton = uibutton(app.PlottingPanel, 'push');
            app.PlotRobotArmButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRobotArmButtonPushed, true);
            app.PlotRobotArmButton.Text = 'Plot Robot Arm';
            app.DriveRobotArmButton = uibutton(app.PlottingPanel, 'push');
            app.DriveRobotArmButton.ButtonPushedFcn = createCallbackFcn(app, @DriveRobotArmButtonPushed, true);
            app.DriveRobotArmButton.Text = 'Drive Robot Arm';
            app.DynamicsPanel = uipanel(app.SimulationTab);
            app.DynamicsPanel.Title = 'Dynamics';
            app.PrintEquationsofMotionButton = uibutton(app.DynamicsPanel, 'push');
            app.PrintEquationsofMotionButton.ButtonPushedFcn = createCallbackFcn(app, @PrintEquationsofMotionButtonPushed, true);
            app.PrintEquationsofMotionButton.Text = 'Print Equations of Motion';
            app.SimulateSystemDynamicsButton = uibutton(app.DynamicsPanel, 'push');
            app.SimulateSystemDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateSystemDynamicsButtonPushed, true);
            app.SimulateSystemDynamicsButton.Text = {'Simulate System'; 'Dynamics'};
            app.IndirectForceControlPanel = uipanel(app.SimulationTab);
            app.IndirectForceControlPanel.Title = 'Indirect Force Control';
            app.ComplianceControlButton = uibutton(app.IndirectForceControlPanel, 'push');
            app.ComplianceControlButton.ButtonPushedFcn = createCallbackFcn(app, @ComplianceControlButtonPushed, true);
            app.ComplianceControlButton.Text = 'Compliance Control';
            app.ImpedanceControlButton = uibutton(app.IndirectForceControlPanel, 'push');
            app.ImpedanceControlButton.ButtonPushedFcn = createCallbackFcn(app, @ImpedanceControlButtonPushed, true);
            app.ImpedanceControlButton.Text = 'Impedance Control';
            app.BacktoArmDefinitionButton = uibutton(app.SimulationTab, 'push');
            app.BacktoArmDefinitionButton.Text = 'Back to Arm Definition';
            app.SystemDynamicsTab = uitab(app.TabGroup);
            app.SystemDynamicsTab.Title = 'System Dynamics';
            app.DefineSimulationParametersPanel = uipanel(app.SystemDynamicsTab);
            app.DefineSimulationParametersPanel.Title = 'Define Simulation Parameters';
            app.FinishTimesEditFieldLabel = uilabel(app.DefineSimulationParametersPanel);
            app.FinishTimesEditFieldLabel.Text = 'Finish Time (s)';
            app.FinishTimesEditField = uieditfield(app.DefineSimulationParametersPanel, 'numeric');
            app.FinishTimesEditField.Value = 3;
            app.EnterJointTorquesasaVectorT1T2TNLabel_2 = uilabel(app.DefineSimulationParametersPanel);
            app.EnterJointTorquesasaVectorT1T2TNLabel_2.Text = {'Enter joint initial values as q1_0,q2_0,....qN_0, '; 'with commas separating values'};
            app.EnterJointTorquesasaVectorT1T2TNLabel_3 = uilabel(app.DefineSimulationParametersPanel);
            app.EnterJointTorquesasaVectorT1T2TNLabel_3.Text = {'Enter joint initial velocities as q1_0'',q2_0'',....qN_0'', '; 'with commas separating values'};
            app.q0degormEditFieldLabel = uilabel(app.DefineSimulationParametersPanel);
            app.q0degormEditFieldLabel.Text = 'q0 (deg or m)';
            app.q0degormEditField = uieditfield(app.DefineSimulationParametersPanel, 'text');
            app.q0degormEditField.Value = '-50,90,30';
            app.q0_dotradsormsEditFieldLabel = uilabel(app.DefineSimulationParametersPanel);
            app.q0_dotradsormsEditFieldLabel.Text = 'q0_dot (rad/s or m/s)';
            app.q0_dotradsormsEditField = uieditfield(app.DefineSimulationParametersPanel, 'text');
            app.q0_dotradsormsEditField.Value = '0,0,0';
            app.SimulateJointDynamicsBasedonInputTorqueLabel = uilabel(app.SystemDynamicsTab);
            app.SimulateJointDynamicsBasedonInputTorqueLabel.Text = 'Simulate Joint Dynamics Based on Input Torque';
            app.Label_3 = uilabel(app.SystemDynamicsTab);
            app.Label_3.Text = '__________________________________________________________________________________';
            app.RunSimulationButton = uibutton(app.SystemDynamicsTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.Panel = uipanel(app.SystemDynamicsTab);
            app.DefineJointTorquesPanel = uipanel(app.SystemDynamicsTab);
            app.DefineJointTorquesPanel.Title = 'Define Joint Torques';
            app.UserInputEditFieldLabel = uilabel(app.DefineJointTorquesPanel);
            app.UserInputEditFieldLabel.Text = 'User Input:';
            app.UserInputEditField = uieditfield(app.DefineJointTorquesPanel, 'text');
            app.UserInputEditField.Value = '200,50,1';
            app.EnterJointTorquesasaVectorT1T2TNLabel = uilabel(app.DefineJointTorquesPanel);
            app.EnterJointTorquesasaVectorT1T2TNLabel.Text = {'Enter Joint Torques as T1,T2,....TN, with '; 'commas separating torques'};
            app.MethodofDefiningTorqueLabel = uilabel(app.DefineJointTorquesPanel);
            app.MethodofDefiningTorqueLabel.Text = {'Method of '; 'DefiningTorque'};
            app.MethodofDefiningTorqueDropDown = uidropdown(app.DefineJointTorquesPanel);
            app.MethodofDefiningTorqueDropDown.Items = {'Enter Constant Values', 'Enter Function by Hand', 'User Provided Function File'};
            app.MethodofDefiningTorqueDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodofDefiningTorqueDropDownValueChanged, true);
            app.MethodofDefiningTorqueDropDown.Value = 'Enter Constant Values';
            app.IndirectForceControlComplianceTab = uitab(app.TabGroup);
            app.IndirectForceControlComplianceTab.Title = 'Indirect Force Control - Compliance';
            app.SimulateButton = uibutton(app.IndirectForceControlComplianceTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @Comp_control, true);
            app.SimulateButton.Text = 'Simulate';
            app.Panel_2 = uipanel(app.IndirectForceControlComplianceTab);
            app.tfEditFieldLabel = uilabel(app.IndirectForceControlComplianceTab);
            app.tfEditFieldLabel.Text = 'tf';
            app.tfEditField = uieditfield(app.IndirectForceControlComplianceTab, 'numeric');
            app.xd6x1columnvectorEditFieldLabel = uilabel(app.IndirectForceControlComplianceTab);
            app.xd6x1columnvectorEditFieldLabel.Text = 'xd (6x1 column vector)';
            app.xd6x1columnvectorEditField = uieditfield(app.IndirectForceControlComplianceTab, 'text');
            app.xd6x1columnvectorEditField.Value = '1,0,0,0,0,0';
            app.he6x1columnvectorEditFieldLabel = uilabel(app.IndirectForceControlComplianceTab);
            app.he6x1columnvectorEditFieldLabel.Text = 'he (6x1 column vector)';
            app.he6x1columnvectorEditField = uieditfield(app.IndirectForceControlComplianceTab, 'text');
            app.he6x1columnvectorEditField.Value = '0,0,0,0,0,0';
            app.t00Label = uilabel(app.IndirectForceControlComplianceTab);
            app.t00Label.Text = 't0                            0';
            app.IndirectForceControlImpedanceTab = uitab(app.TabGroup);
            app.IndirectForceControlImpedanceTab.Title = 'Indirect Force Control - Impedance';
            app.kpEditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.kpEditFieldLabel.Text = 'kp';
            app.kpEditField = uieditfield(app.IndirectForceControlImpedanceTab, 'numeric');
            app.kpEditField.Value = 5;
            app.xdEditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.xdEditFieldLabel.Text = 'xd';
            app.xdEditField = uieditfield(app.IndirectForceControlImpedanceTab, 'text');
            app.xdEditField.Value = '1,0,0,0,0,0';
            app.he6x1columnvectorEditField_2Label = uilabel(app.IndirectForceControlImpedanceTab);
            app.he6x1columnvectorEditField_2Label.Text = 'he (6x1 column vector)';
            app.he6x1columnvectorEditField_2 = uieditfield(app.IndirectForceControlImpedanceTab, 'text');
            app.he6x1columnvectorEditField_2.Value = '0,0,0,0,0,0';
            app.SimulateButton_2 = uibutton(app.IndirectForceControlImpedanceTab, 'push');
            app.SimulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_2Pushed, true);
            app.SimulateButton_2.Text = 'Simulate';
            app.dtEditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.IndirectForceControlImpedanceTab, 'numeric');
            app.dtEditField.Value = 0.01;
            app.xd_ddotEditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.xd_ddotEditFieldLabel.Text = 'xd_ddot';
            app.xd_ddotEditField = uieditfield(app.IndirectForceControlImpedanceTab, 'text');
            app.xd_ddotEditField.Value = '0,0,0,0,0,0';
            app.xd_dotEditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.xd_dotEditFieldLabel.Text = 'xd_dot';
            app.xd_dotEditField = uieditfield(app.IndirectForceControlImpedanceTab, 'text');
            app.xd_dotEditField.Value = '0,0,0,0,0,0';
            app.kdEditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.kdEditFieldLabel.Text = 'kd';
            app.kdEditField = uieditfield(app.IndirectForceControlImpedanceTab, 'numeric');
            app.kdEditField.Value = 5;
            app.q0EditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.q0EditFieldLabel.Text = 'q0';
            app.q0EditField = uieditfield(app.IndirectForceControlImpedanceTab, 'text');
            app.q0EditField.Value = '0,0,0';
            app.MdDiagonalElementsEditFieldLabel = uilabel(app.IndirectForceControlImpedanceTab);
            app.MdDiagonalElementsEditFieldLabel.Text = 'Md Diagonal Elements';
            app.MdDiagonalElementsEditField = uieditfield(app.IndirectForceControlImpedanceTab, 'text');
            app.MdDiagonalElementsEditField.Value = '1,1,1,1,1,1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboticsPackage_part4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end